classdef APPMAVSim < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        MAVSimLabel                 matlab.ui.control.Label
        TabGroup                    matlab.ui.container.TabGroup
        TCPTab                      matlab.ui.container.Tab
        Unity3DPanel                matlab.ui.container.Panel
        PortEditField_7Label        matlab.ui.control.Label
        PortEditField               matlab.ui.control.NumericEditField
        TimeoutEditField_7Label     matlab.ui.control.Label
        TimeoutEditField            matlab.ui.control.NumericEditField
        IPEditField_7Label          matlab.ui.control.Label
        IPEditField                 matlab.ui.control.EditField
        LinuxEmbeddedComputerPanel  matlab.ui.container.Panel
        PortEditField_8Label        matlab.ui.control.Label
        PortEditField_2             matlab.ui.control.NumericEditField
        TimeoutEditField_8Label     matlab.ui.control.Label
        TimeoutEditField_2          matlab.ui.control.NumericEditField
        IPEditField_8Label          matlab.ui.control.Label
        IPEditField_2               matlab.ui.control.EditField
        ResetButton                 matlab.ui.control.Button
        SaveButton                  matlab.ui.control.Button
        MAVTab                      matlab.ui.container.Tab
        nrEditFieldLabel            matlab.ui.control.Label
        nrEditField                 matlab.ui.control.NumericEditField
        ResetButton_2               matlab.ui.control.Button
        ResetButton_3               matlab.ui.control.Button
        SaveButton_3                matlab.ui.control.Button
        SaveButton_2                matlab.ui.control.Button
        ResetButton_4               matlab.ui.control.Button
        SaveButton_4                matlab.ui.control.Button
        ResetButton_5               matlab.ui.control.Button
        SaveButton_5                matlab.ui.control.Button
        ResetButton_6               matlab.ui.control.Button
        SaveButton_6                matlab.ui.control.Button
        ResetButton_7               matlab.ui.control.Button
        SaveButton_7                matlab.ui.control.Button
        ResetButton_8               matlab.ui.control.Button
        SaveButton_8                matlab.ui.control.Button
        ControlAllocationTab        matlab.ui.container.Tab
        ResetButton_9               matlab.ui.control.Button
        SaveButton_9                matlab.ui.control.Button
        NavigationTab               matlab.ui.container.Tab
        SensorsTab                  matlab.ui.container.Tab
        DisturbanceTab              matlab.ui.container.Tab
        AttitudeTab                 matlab.ui.container.Tab
        PositionVelocityTab         matlab.ui.container.Tab
        TrajectoryPlanningTab       matlab.ui.container.Tab
        StatusTextAreaLabel         matlab.ui.control.Label
        StatusTextArea              matlab.ui.control.TextArea
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % COMMUNICATION
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            % TCP socket with Unity 3D
            
            ip_unity   = '127.0.0.1'; 
            port_unity = 55001;
            tout_unity = 10;
            role_unity = 'client';
            byteorder_unity = 'littleEndian';
            nele_unity = 10;

            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % PLANT PARAMETERS
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % MAV %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            app.nrEditField.Value    = 4;        
%             app.EditFieldl1.Value    = 0.25;
%             app.EditFieldl2.Value    = 0.25;
%             app.EditFieldkf.Value    = 1.2838e-5;
%             app.EditFieldkt.Value    = 3.0811e-07;
%             app.EditFieldwmax.Value  = 0.9*9620*(2*pi/60); 
%             app.EditFieldkm.Value    = 1;
%             app.EditFieldTm.Value    = 0.01;
%             app.EditFieldm.Value     = 1;
%             app.EditFieldJB.Value    = [0.015,0,0;0,0.015,0;0,0,0.03];
%             app.EditFieldJr.Value    = 0.0005;
%             app.EditFieldg.Value     = 9.81;
%             


            % Disturbance %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            alpha_t = [0.003;0.003;0.003];
            alpha_f = [0.02;0.02;0.02];
            
            
            % Sensors %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            sa  = [0.0039 0.0039 0.0039];
            sg  = [0.001 0.001 0.001];
            sm  = [0.002 0.002 0.002];
            sba = [0.0001 0.0001 0.0001]*0;
            sbg = [0.0001 0.0001 0.0001];
            sbm = [0.0001 0.0001 0.0001];
            sr  = [0.01 0.01 0.01];
            mg  = [0.5 -0.8 0.002]';
            ba0 = [0.02 -0.01 0.05]';
            bg0 = [0.02 -0.01 0.05]';
            bm0 = [0.001 0.001 0.001]';


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % CONTROL SYSTEM PARAMETERS
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % Joystick %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            vxmax = 2.0;       
            vymax = 2.0;       
            vzmax = 2.0;       
            wzmax = 1.0;       
            
            
            % Position/velocity control law %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            K3     = 0.3*eye(3);        
            K4     = 1.0*eye(3);         
            Kc     = diag([1.5,1.5,3]);
            Fmin   = [-5.66;-5.66;0.98];
            Fmax   = [5.66;5.66;19.62];
            tau_ref_filter = 1;


            % Attitude control law %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            K1   = diag([20 20 5]);      
            K2   = diag([10 10 5]);    
            Tmin = -[2.21;2.21;0.21];
            Tmax =  [2.21;2.21;0.21];
            
            
            % Control allocation  %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            zetamin = 0.25;
            zetamax = 4.65;
            
            
            % Trajectory planning or guidance %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            wl    = [[0 0 2 0]',[5 0 2 0]',[5 5 2 0]',[5 5 0 0]' ];  
            Kpr   = diag([0.05 0.05 0]);  
            Kpp   = 0;
            Kdr   = diag([0.05 0.05 0]);  
            Kdp   = 0;
            rhor  = 0.5;
            rhop  = 5*pi/180;
            dtl   = [0 0 0 0];
            Ts    = 0.01;
            tf    = inf;
            
            % Auto take-off
            
            htakeoff = 1;


            % Navigation algorithm %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            Rr  = diag([0.01^2,0.01^2,0.01^2]);        
            Ra  = diag([0.05^2,0.05^2,0.05^2]);
            Rg  = diag([0.001^2,0.001^2,0.001^2]);       
            Rm  = diag([0.05^2,0.05^2,0.05^2]);
            Qba = diag([0.001^2,0.001^2,0.001^2]);
            Qbg = diag([0.000001^2,0.000001^2,0.000001^2]);       
            Qbm = diag([0.00005^2,0.00005^2,0.00005^2]);       
            P0  = blkdiag(0.0001*eye(3),0.0001*eye(3),0.0001*eye(3),0.01*eye(4),0.0001*eye(3));
            x0  = [zeros(1,6) ba0' zeros(1,3) 1 bg0']' + sqrtm(P0)*randn(16,1);
            q0  = x0(10:13)/norm(x0(10:13)); x0(10:13) = q0;
            tau = 0.1;
            
            
            x0 = [-0.00474200453136375;0.00340559089522441;-0.00606135915548891;-0.0631804903110595;0.0467605139052012;-0.0518893463786147;0;0;0;0.000215665657103282;-0.000748770998040058;-0.000830264307180187;0.999999351745538;0.0199597004279470;-0.00998300941645958;0.0500399898108888];
            P0 = [0.000100000000000000,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0;0,0.000100000000000000,0,0,0,0,0,0,0,0,0,0,0,0,0,0;0,0,0.000100000000000000,0,0,0,0,0,0,0,0,0,0,0,0,0;0,0,0,2,0,0,0,0,0,0,0,0,0,0,0,0;0,0,0,0,2,0,0,0,0,0,0,0,0,0,0,0;0,0,0,0,0,2,0,0,0,0,0,0,0,0,0,0;0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0;0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0;0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0;0,0,0,0,0,0,0,0,0,1.11218963620693e-06,-2.15480576697530e-07,1.33790181604220e-09,-4.67157069073927e-10,-6.19424530389513e-08,1.32520391875544e-08,-6.05904127092322e-11;0,0,0,0,0,0,0,0,0,-2.15480576588521e-07,1.32138858621344e-06,-1.32955444638817e-09,1.05623116431640e-09,1.33479432584375e-08,-7.48374065493002e-08,7.04348022586673e-11;0,0,0,0,0,0,0,0,0,1.33790174494013e-09,-1.32955443469627e-09,1.45632085877029e-06,1.18087985643983e-09,-1.55940009237848e-10,6.99442930021848e-11,-8.31702655447309e-08;0,0,0,0,0,0,0,0,0,-4.67157069474037e-10,1.05623116266691e-09,1.18087985606255e-09,7.81268819058471e-08,1.98327165061224e-11,-5.91894146368172e-11,-7.08599263434571e-11;0,0,0,0,0,0,0,0,0,-6.19424530389610e-08,1.33479432638786e-08,-1.55940012789288e-10,1.98327164912398e-11,3.12063637637586e-08,-1.32968853478652e-09,1.92113072024263e-11;0,0,0,0,0,0,0,0,0,1.32520391821150e-08,-7.48374065492821e-08,6.99442935717065e-11,-5.91894146878525e-11,-1.32968853451247e-09,3.24951447986039e-08,-1.75737057985910e-12;0,0,0,0,0,0,0,0,0,-6.05904091718709e-11,7.04348016780740e-11,-8.31702655447313e-08,-7.08599263554159e-11,1.92113070237341e-11,-1.75737055088677e-12,3.33282304415187e-08];
            
            kfcalib = 0;


            %app.PortEditField.Value = 0;
            
            
        end

        % Callback function
        function APLICARButtonPushed(app, event)
           app.PortEditField.Value = app.PortEditField.Value + 1;
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.MAVSimLabel = uilabel(app.UIFigure);
            app.MAVSimLabel.Text = 'MAVSim';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TCPTab = uitab(app.TabGroup);
            app.TCPTab.Title = 'TCP';
            app.Unity3DPanel = uipanel(app.TCPTab);
            app.Unity3DPanel.Title = 'Unity 3D';
            app.PortEditField_7Label = uilabel(app.Unity3DPanel);
            app.PortEditField_7Label.Text = 'Port';
            app.PortEditField = uieditfield(app.Unity3DPanel, 'numeric');
            app.TimeoutEditField_7Label = uilabel(app.Unity3DPanel);
            app.TimeoutEditField_7Label.Text = 'Time out';
            app.TimeoutEditField = uieditfield(app.Unity3DPanel, 'numeric');
            app.IPEditField_7Label = uilabel(app.Unity3DPanel);
            app.IPEditField_7Label.Text = 'IP';
            app.IPEditField = uieditfield(app.Unity3DPanel, 'text');
            app.LinuxEmbeddedComputerPanel = uipanel(app.TCPTab);
            app.LinuxEmbeddedComputerPanel.Title = 'Linux Embedded Computer';
            app.PortEditField_8Label = uilabel(app.LinuxEmbeddedComputerPanel);
            app.PortEditField_8Label.Text = 'Port';
            app.PortEditField_2 = uieditfield(app.LinuxEmbeddedComputerPanel, 'numeric');
            app.TimeoutEditField_8Label = uilabel(app.LinuxEmbeddedComputerPanel);
            app.TimeoutEditField_8Label.Text = 'Time out';
            app.TimeoutEditField_2 = uieditfield(app.LinuxEmbeddedComputerPanel, 'numeric');
            app.IPEditField_8Label = uilabel(app.LinuxEmbeddedComputerPanel);
            app.IPEditField_8Label.Text = 'IP';
            app.IPEditField_2 = uieditfield(app.LinuxEmbeddedComputerPanel, 'text');
            app.ResetButton = uibutton(app.TCPTab, 'push');
            app.ResetButton.Text = 'Reset';
            app.SaveButton = uibutton(app.TCPTab, 'push');
            app.SaveButton.Text = 'Save';
            app.MAVTab = uitab(app.TabGroup);
            app.MAVTab.Title = 'MAV';
            app.ResetButton_2 = uibutton(app.MAVTab, 'push');
            app.ResetButton_2.Text = 'Reset';
            app.SaveButton_2 = uibutton(app.MAVTab, 'push');
            app.SaveButton_2.Text = 'Save';
            app.nrEditFieldLabel = uilabel(app.MAVTab);
            app.nrEditFieldLabel.Text = 'nr';
            app.nrEditField = uieditfield(app.MAVTab, 'numeric');
            app.ControlAllocationTab = uitab(app.TabGroup);
            app.ControlAllocationTab.Title = 'Control Allocation';
            app.ResetButton_3 = uibutton(app.ControlAllocationTab, 'push');
            app.ResetButton_3.Text = 'Reset';
            app.SaveButton_3 = uibutton(app.ControlAllocationTab, 'push');
            app.SaveButton_3.Text = 'Save';
            app.NavigationTab = uitab(app.TabGroup);
            app.NavigationTab.Title = 'Navigation';
            app.ResetButton_4 = uibutton(app.NavigationTab, 'push');
            app.ResetButton_4.Text = 'Reset';
            app.SaveButton_4 = uibutton(app.NavigationTab, 'push');
            app.SaveButton_4.Text = 'Save';
            app.SensorsTab = uitab(app.TabGroup);
            app.SensorsTab.Title = 'Sensors';
            app.ResetButton_5 = uibutton(app.SensorsTab, 'push');
            app.ResetButton_5.Text = 'Reset';
            app.SaveButton_5 = uibutton(app.SensorsTab, 'push');
            app.SaveButton_5.Text = 'Save';
            app.DisturbanceTab = uitab(app.TabGroup);
            app.DisturbanceTab.Title = 'Disturbance';
            app.ResetButton_6 = uibutton(app.DisturbanceTab, 'push');
            app.ResetButton_6.Text = 'Reset';
            app.SaveButton_6 = uibutton(app.DisturbanceTab, 'push');
            app.SaveButton_6.Text = 'Save';
            app.AttitudeTab = uitab(app.TabGroup);
            app.AttitudeTab.Title = 'Attitude';
            app.ResetButton_7 = uibutton(app.AttitudeTab, 'push');
            app.ResetButton_7.Text = 'Reset';
            app.SaveButton_7 = uibutton(app.AttitudeTab, 'push');
            app.SaveButton_7.Text = 'Save';
            app.PositionVelocityTab = uitab(app.TabGroup);
            app.PositionVelocityTab.Title = 'Position/Velocity';
            app.ResetButton_8 = uibutton(app.PositionVelocityTab, 'push');
            app.ResetButton_8.Text = 'Reset';
            app.SaveButton_8 = uibutton(app.PositionVelocityTab, 'push');
            app.SaveButton_8.Text = 'Save';
            app.TrajectoryPlanningTab = uitab(app.TabGroup);
            app.TrajectoryPlanningTab.Title = 'Trajectory Planning';
            app.ResetButton_9 = uibutton(app.TrajectoryPlanningTab, 'push');
            app.ResetButton_9.Text = 'Reset';
            app.SaveButton_9 = uibutton(app.TrajectoryPlanningTab, 'push');
            app.SaveButton_9.Text = 'Save';
            app.StatusTextAreaLabel = uilabel(app.UIFigure);
            app.StatusTextAreaLabel.Text = 'Status';
            app.StatusTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APPMAVSim

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end